classdef cropRotate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        BackButton         matlab.ui.control.Button
        Image              matlab.ui.control.Image
        CropButton         matlab.ui.control.Button
        SaveChangesButton  matlab.ui.control.Button
        RotateSlider       matlab.ui.control.Slider
        RotateSliderLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagePath % Path to selected image
    end
methods (Access = public)
        % Function to rotate and display image
        function rotateAndDisplayImage(app, angle)
            if isempty(app.ImagePath)
                app.Image.ImageSource = app.ImagePath;
            end
            
            % Load the original image data
            imageData = imread(app.Image.ImageSource);
            
            % Rotate the image
            rotatedImage = imrotate(imageData, angle, 'bilinear', 'crop');
            
            % Display rotated image
            imshow(rotatedImage, 'Parent', app.UIAxes);

            app.UIAxes.UserData.ImageSource = rotatedImage;
        end
        
        function setImagePath(app,imagePath)
            app.ImagePath = imagePath;
            if ~isempty(app.ImagePath)
                app.Image.ImageSource = app.ImagePath;
                imageData = imread(app.ImagePath);
                imshow(imageData,'Parent',app.UIAxes);
                app.UIAxes.UserData.ImageSource = imageData;
            end
        end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveChangesButton
        function SaveChangesButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                if isfield(app.UIAxes.UserData, 'CroppedImage')
                    imageToSave = app.UIAxes.UserData.CroppedImage;
                else
                    imageToSave = app.UIAxes.UserData.ImageSource;
                end
                
                % Get the original file name and path
                [filepath, name, ext] = fileparts(app.ImagePath);
                
                % Create new file name
                newFileName = fullfile(filepath, [name '_crop' ext]);
                
                % Ensure the new file name is unique
                counter = 1;
                while isfile(newFileName)
                    newFileName = fullfile(filepath, [name '_crop_' num2str(counter) ext]);
                    counter = counter + 1;
                end
                
                % Save the modified image
                imwrite(imageToSave, newFileName);
                
                % Create a new instance of first_interface
                firstInterface = first_interface;
                
                % Set the image path in the new first_interface to the new file
                firstInterface.setImagePath(newFileName);
                
                % Make the new first_interface visible
                firstInterface.UIFigure.Visible = 'on';
                
                % Close the current cropRotate app
                delete(app);
            else
                uialert(app.UIFigure, 'No image to save.', 'Save Error');
            end
        end

        % Value changing function: RotateSlider
        function RotateSliderValueChanging(app, event)
            changingValue = event.Value;
            app.rotateAndDisplayImage(changingValue);
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            if isfield(app.UIAxes.UserData, 'ImageSource')
                imageData = app.UIAxes.UserData.ImageSource;
        
                % Let the user draw a rectangle on the image
                h = drawrectangle(app.UIAxes);
                
                % Display dialog box
                uialert(app.UIFigure, 'Press Enter to crop photo', 'Crop Instructions', 'Icon', 'info');
                
                wait(h); % Wait for the user to double-click or press Enter
        
                % Get the position of the rectangle
                position = h.Position;
        
                % Crop the image
                croppedImage = imcrop(imageData, position);
        
                % Display the cropped image
                imshow(croppedImage, 'Parent', app.UIAxes);
        
                % Store the cropped image in UserData
                app.UIAxes.UserData.CroppedImage = croppedImage;
        
                delete(h);
            else
                uialert(app.UIFigure, 'No image to crop.', 'Crop Error');
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            firstInterface = first_interface;
            firstInterface.setImagePath(app.ImagePath);
            firstInterface.UIFigure.Visible = 'on';
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Changes')
            colormap(app.UIAxes, 'white')
            app.RotateSliderLabel = uilabel(app.UIFigure);
            app.RotateSliderLabel.Text = 'Rotate';
            app.RotateSlider = uislider(app.UIFigure);
            app.RotateSlider.Limits = [0 360];
            app.RotateSlider.ValueChangingFcn = createCallbackFcn(app, @RotateSliderValueChanging, true);
            app.SaveChangesButton = uibutton(app.UIFigure, 'push');
            app.SaveChangesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChangesButtonPushed, true);
            app.SaveChangesButton.Text = 'Save Changes';
            app.CropButton = uibutton(app.UIFigure, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.Image = uiimage(app.UIFigure);
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cropRotate

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end